classdef PuzzleSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PuzzleSolverUIFigure   matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        EditField_2            matlab.ui.control.NumericEditField
        EditField_3            matlab.ui.control.NumericEditField
        EditField              matlab.ui.control.NumericEditField
        EditField_4            matlab.ui.control.NumericEditField
        EditField_5            matlab.ui.control.NumericEditField
        EditField_6            matlab.ui.control.NumericEditField
        EditField_7            matlab.ui.control.NumericEditField
        EditField_8            matlab.ui.control.NumericEditField
        EditField_9            matlab.ui.control.NumericEditField
        PuzzleSolverLabel      matlab.ui.control.Label
        SolveButton            matlab.ui.control.Button
        CommentsTextAreaLabel  matlab.ui.control.Label
        CommentsTextArea       matlab.ui.control.TextArea
        ReplayButton           matlab.ui.control.Button
        ResetButton            matlab.ui.control.Button
        NextButton             matlab.ui.control.Button
        CloseButton            matlab.ui.control.Button
    end


    properties (Access = private)
        cellPos = [10 190 80 80; 10 100 80 80; 10 10 80 80;...
                   100 190 80 80; 100 100 80 80; 100 10 80 80;...
                   190 190 80 80; 190 100 80 80; 190 10 80 80];
        goalNode = ones(3,3)*-1;
        initialNode = ones(3,3)*-1;
        nodePath
        zeroField
    end

    methods (Access = private)
    
        function field = identifyField(app, num)
            pos = app.cellPos(num,:);
            if all(app.EditField.Position == pos)
                field = app.EditField;
            elseif all(app.EditField_2.Position == pos)
                field = app.EditField_2;
            elseif all(app.EditField_3.Position == pos)
                field = app.EditField_3;
            elseif all(app.EditField_4.Position == pos)
                field = app.EditField_4;
            elseif all(app.EditField_5.Position == pos)
                field = app.EditField_5;
            elseif all(app.EditField_6.Position == pos)
                field = app.EditField_6;
            elseif all(app.EditField_7.Position == pos)
                field = app.EditField_7;
            elseif all(app.EditField_8.Position == pos)
                field = app.EditField_8;
            elseif all(app.EditField_9.Position == pos)
                field = app.EditField_9;
            else
                field = [];
            end
        end
        
        function NodeSet = nodesGenerator(app)
            % This function takes as input the initial node. The output of the function is
            % a nodeset containing all possible nodes from initial node to the goal node.
            
            Nodes = [];
            NodesInfo = []; % NodeInfo = [Node #, Parent node #, CostToCome]
            
            Nodes(:,:,1) = app.initialNode;
            NodesInfo(1,:) = [nodeNumber(Nodes(:,:,1)),0,0];
            
            i = 0;
            goalReached = false;
            while ~goalReached
                i = i + 1;
                if i > size(Nodes,3)
                    break;
                end
                currentNode = Nodes(:,:,i);
                % Move the blank space left
                [newNode, status] = moveLeft(currentNode);
                if status && ~any(NodesInfo(:,1) == nodeNumber(newNode))
                    Nodes(:,:,size(Nodes,3)+1) = newNode;
                    NodesInfo(size(Nodes,3),:) = [nodeNumber(newNode), nodeNumber(currentNode), i];
                end
                % Move the blank space right
                [newNode, status] = moveRight(currentNode);
                if status && ~any(NodesInfo(:,1) == nodeNumber(newNode))
                    Nodes(:,:,size(Nodes,3)+1) = newNode;
                    NodesInfo(size(Nodes,3),:) = [nodeNumber(newNode), nodeNumber(currentNode), i];
                end
                % Move the blank space up
                [newNode, status] = moveUp(currentNode);
                if (status)&&(~any(NodesInfo(:,1) == nodeNumber(newNode)))
                    Nodes(:,:,size(Nodes,3)+1) = newNode;
                    NodesInfo(size(Nodes,3),:) = [nodeNumber(newNode), nodeNumber(currentNode), i];
                end
                % Move the blank space down
                [newNode, status] = moveDown(currentNode);
                if (status)&&(~any(NodesInfo(:,1) == nodeNumber(newNode)))
                    Nodes(:,:,size(Nodes,3)+1) = newNode;
                    NodesInfo(size(Nodes,3),:) = [nodeNumber(newNode), nodeNumber(currentNode), i];
                end
                if any(NodesInfo(:,1) == nodeNumber(app.goalNode))
                    goalReached = true;
                end
            end
            
            NodeSet.Nodes = Nodes;
            NodeSet.NodesInfo = NodesInfo;
        end
        
        function reachGoal(app, NodeSet)
            % This function reaches the goal node from the specified initial node.
            
            i = 1;
            app.nodePath(:,:,i) = app.goalNode;
            parentNode = NodeSet.NodesInfo(NodeSet.NodesInfo(:,1) == nodeNumber(app.goalNode),2);
            while parentNode ~= 0
                i = i+1;
                parentNodePos = find(NodeSet.NodesInfo(:,1) == parentNode);
                app.nodePath(:,:,i) = NodeSet.Nodes(:,:,parentNodePos);
                parentNode = NodeSet.NodesInfo(parentNodePos,2);
            end
            app.nodePath = flip(app.nodePath, 3);
        end
        
        function plotPath(app, nodePath)
            % This function plots the puzzle solver using node path from the initial node to the goal node.

            currentNode = nodePath(:,:,1);
            nextBlank = find(currentNode == 0);
            for n = 2:size(nodePath,3)
                currentBlank = nextBlank;
                nextNode = nodePath(:,:,n);
                nextBlank = find(nextNode == 0);
                changePos = [app.cellPos(nextBlank,:); app.cellPos(currentBlank,:)];
                traverse = zeros(100,4);
                for i = 1:4
                    traverse(:,i) = linspace(changePos(1,i),changePos(2,i))';
                end
                field = identifyField(app, nextBlank);
                for t = 1:100
                    field.Position = traverse(t,:);
                    app.zeroField.Position = traverse(end-t+1,:);
                    pause(0.01);
                end
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            pause on
            app.CommentsTextArea.FontSize = 14;
            % Read the goal state
            for i = 1:9
                field = identifyField(app, i);
                if isempty(field)
                    app.CommentsTextArea.Value = {'Cannot identify the blocks. Please check and try again.'};
                    return;
                elseif any(app.goalNode == field.Value)
                    app.CommentsTextArea.Value = {'The goal state is invalid. Please check and try again.'};
                    return;
                end
                app.goalNode(i) = field.Value;
            end
            % Reset the puzzle to initial node
            for i = 1:9
                field = identifyField(app, i);
                if isempty(field)
                    app.CommentsTextArea.Value = {'Cannot identify the blocks. Please check and try again.'};
                    return;
                end
                field.Value = app.initialNode(i);
            end
            % Generate nodes
            app.CommentsTextArea.Value = {'Generating all possible nodes from initial node to goal node.'};
            pause(5)
            NodeSet = nodesGenerator(app);
            if isempty(NodeSet)
                app.CommentsTextArea.Value = {'Nodes could not be generated. Please check and try again.'};
                return;
            elseif ~any(NodeSet.NodesInfo(:,1) == nodeNumber(app.initialNode))
                app.CommentsTextArea.Value = {'Initial node not found among the nodes generated. Please check and try again.'};
                
                return;
            elseif ~any(NodeSet.NodesInfo(:,1) == nodeNumber(app.goalNode))
                app.CommentsTextArea.Value = {'Goal node not found among the nodes generated.'};
                pause(5)
                ResetButtonPushed(app, event);
                return;
            end
            app.CommentsTextArea.Value = {'All possible nodes from the given initial node has been generated successfully.'};
            pause(5)
            % Generate the path
            app.CommentsTextArea.Value = {'Generating the desired path.'};
            pause(5)
            reachGoal(app, NodeSet);
            if nodeNumber(app.nodePath(:,:,1)) ~= nodeNumber(app.initialNode)
                app.CommentsTextArea.Value = {'The first state in the node path is not the initial node. Please check and try again.'};
                return;
            elseif nodeNumber(app.nodePath(:,:,end)) ~= nodeNumber(app.goalNode)
                app.CommentsTextArea.Value = {'The last state in the node path is not the goal node. Please check and try again.'};
                return;
            end
            app.CommentsTextArea.Value = {'Path from the initial node to the goal node generated successfully.'};
            pause(5)
            % Start solving the puzzle
            blankPos = find(app.initialNode == 0);
            app.zeroField = identifyField(app, blankPos);
            app.zeroField.Visible = 'off';
            app.CommentsTextArea.Value = {'Solving the puzzle.'};
            pause(5)
            plotPath(app, app.nodePath);
            app.CommentsTextArea.Value = {'Goal node successfully reached.'};
            app.SolveButton.Visible = 'off';
            app.ReplayButton.Visible = 'on';
            app.ResetButton.Visible = 'on';
        end

        % Button pushed function: ReplayButton
        function ReplayButtonPushed(app, event)
            app.CommentsTextArea.FontSize = 14;
            app.CommentsTextArea.Value = {'Replaying the simulation.'};
            pause(5)
            % Reset the puzzle to initial node
            app.zeroField.Visible = 'on';
            for i = 1:9
                field = identifyField(app, i);
                if isempty(field)
                    app.CommentsTextArea.Value = {'Cannot identify the blocks. Please check and try again.'};
                    return;
                end
                field.Value = app.initialNode(i);
            end
            % Start solving the puzzle
            blankPos = find(app.initialNode == 0);
            app.zeroField = identifyField(app, blankPos);
            app.zeroField.Visible = 'off';
            app.CommentsTextArea.Value = {'Solving the puzzle.'};
            pause(5)
            plotPath(app, app.nodePath);
            app.CommentsTextArea.Value = {'Goal node successfully reached.'};
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.CommentsTextArea.Value = {'Set the initial state.'};
            app.CommentsTextArea.FontSize = 18;
            app.goalNode = ones(3,3)*-1;
            % Reset the puzzle to initial node
            for i = 1:9
                field = identifyField(app, i);
                if isempty(field)
                    app.CommentsTextArea.Value = {'Cannot identify the blocks. Please check and try again.'};
                    return;
                end
                field.Value = app.initialNode(i);
            end
            app.initialNode = ones(3,3)*-1;
            app.NextButton.Visible = 'on';
            app.nodePath = [];
            app.ReplayButton.Visible = 'off';
            app.ResetButton.Visible = 'off';
            app.zeroField.Visible = 'on';
            app.zeroField = [];
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.CommentsTextArea.FontSize = 14;
            for i = 1:9
                field = identifyField(app, i);
                if isempty(field)
                    app.CommentsTextArea.Value = {'Cannot identify the blocks. Please check and try again.'};
                    return;
                elseif any(app.initialNode == field.Value)
                    app.CommentsTextArea.Value = {'The initial state is invalid. Please check and try again.'};
                    return;
                end
                app.initialNode(i) = field.Value;
            end
            app.CommentsTextArea.FontSize = 18;
            app.NextButton.Visible = 'off';
            app.SolveButton.Visible = 'on';
            app.CommentsTextArea.Value = {'Set the goal state.'};
            goalState = [1 2 3; 4 5 6; 7 8 0];
            for i = 1:9
                field = identifyField(app, i);
                if isempty(field)
                    app.CommentsTextArea.Value = {'Cannot identify the blocks. Please check and try again.'};
                    return;
                end
                field.Value = goalState(i);
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PuzzleSolverUIFigure = uifigure;
            app.PuzzleSolverUIFigure.Name = 'Puzzle-Solver';
            app.Panel = uipanel(app.PuzzleSolverUIFigure);
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.Limits = [0 8];
            app.EditField_2.RoundFractionalValues = 'on';
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.EditField_2.Value = 5;
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.Limits = [0 8];
            app.EditField_3.RoundFractionalValues = 'on';
            app.EditField_3.ValueDisplayFormat = '%.0f';
            app.EditField_3.Value = 3;
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Limits = [0 8];
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.Value = 2;
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.EditField_4.Limits = [0 8];
            app.EditField_4.RoundFractionalValues = 'on';
            app.EditField_4.ValueDisplayFormat = '%.0f';
            app.EditField_4.Value = 1;
            app.EditField_5 = uieditfield(app.Panel, 'numeric');
            app.EditField_5.Limits = [0 8];
            app.EditField_5.RoundFractionalValues = 'on';
            app.EditField_5.ValueDisplayFormat = '%.0f';
            app.EditField_6 = uieditfield(app.Panel, 'numeric');
            app.EditField_6.Limits = [0 8];
            app.EditField_6.RoundFractionalValues = 'on';
            app.EditField_6.ValueDisplayFormat = '%.0f';
            app.EditField_6.Value = 6;
            app.EditField_7 = uieditfield(app.Panel, 'numeric');
            app.EditField_7.Limits = [0 8];
            app.EditField_7.RoundFractionalValues = 'on';
            app.EditField_7.ValueDisplayFormat = '%.0f';
            app.EditField_7.Value = 4;
            app.EditField_8 = uieditfield(app.Panel, 'numeric');
            app.EditField_8.Limits = [0 8];
            app.EditField_8.RoundFractionalValues = 'on';
            app.EditField_8.ValueDisplayFormat = '%.0f';
            app.EditField_8.Value = 7;
            app.EditField_9 = uieditfield(app.Panel, 'numeric');
            app.EditField_9.Limits = [0 8];
            app.EditField_9.RoundFractionalValues = 'on';
            app.EditField_9.ValueDisplayFormat = '%.0f';
            app.EditField_9.Value = 8;
            app.PuzzleSolverLabel = uilabel(app.PuzzleSolverUIFigure);
            app.PuzzleSolverLabel.Text = 'Puzzle Solver';
            app.SolveButton = uibutton(app.PuzzleSolverUIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Visible = 'off';
            app.SolveButton.Text = 'Solve';
            app.CommentsTextAreaLabel = uilabel(app.PuzzleSolverUIFigure);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.PuzzleSolverUIFigure);
            app.CommentsTextArea.Editable = 'off';
            app.CommentsTextArea.Value = {'Set the initial state.'};
            app.ReplayButton = uibutton(app.PuzzleSolverUIFigure, 'push');
            app.ReplayButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayButton.Visible = 'off';
            app.ReplayButton.Text = 'Replay';
            app.ResetButton = uibutton(app.PuzzleSolverUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.NextButton = uibutton(app.PuzzleSolverUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.CloseButton = uibutton(app.PuzzleSolverUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PuzzleSolver

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PuzzleSolverUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PuzzleSolverUIFigure)
        end
    end
end